classdef Seletor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MaxSlider                  matlab.ui.control.Slider
        MaxSliderLabel             matlab.ui.control.Label
        MinSlider                  matlab.ui.control.Slider
        MinSliderLabel             matlab.ui.control.Label
        CoordenadasEditField       matlab.ui.control.EditField
        CoordenadasEditFieldLabel  matlab.ui.control.Label
        PixelSlider                matlab.ui.control.Slider
        PixelSliderLabel           matlab.ui.control.Label
        SensibilidadeSlider        matlab.ui.control.Slider
        SensibilidadeSliderLabel   matlab.ui.control.Label
        PredefinedImagesLabel      matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        LoadButton                 matlab.ui.control.Button
        RedAxes                    matlab.ui.control.UIAxes
        GreenAxes                  matlab.ui.control.UIAxes
        BlueAxes                   matlab.ui.control.UIAxes
        ImageAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        pixelValue % Description
        SensiValue
        FileName
        MaxValue
        MinValue

    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile, mode ,pixelQ, sensibilityT)
            % For corn.tif, read the second image in the file
            if mode == 0
                if strcmp(imagefile,'template1.jpg')
                    im_rgb = imread('template1.jpg');
                    im_hsv = rgb2hsv(im_rgb);
                    im = im_hsv(:,:,2);
                else
                    try
                        im_rgb = imread(imagefile);
                        im_hsv = rgb2hsv(im_rgb);
                        im = im_hsv(:,:,2);
                    catch ME
                        % If problem reading image, display error message
                        uialert(app.UIFigure, ME.message, 'Image Error');
                        return;
                    end            
                end
            else
                im_rgb = imread(imagefile);
                im_hsv = rgb2hsv(im_rgb);
                im = im_hsv(:,:,2);
                im = imcomplement(im);
                im = bwareaopen((imbinarize(im, sensibilityT)),ceil(pixelQ),8);
                %im = bwareafilt(bwareaopen((imbinarize(im, sensibilityT)),ceil(pixelQ),8), [ceil(app.MinSlider.Value) ceil(app.MaxSlider.Value)]);
            end
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im_rgb, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im_rgb, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im_rgb, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, im_rgb(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im_rgb(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im_rgb(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxes.YLim = [0 maxcount];
                app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxes.YLim = [0 maxcount];
                app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxes.YLim = [0 maxcount];
                app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            app.FileName = 'template2.jpg';
            updateimage(app, app.FileName, 0, 0, 0);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            app.FileName = app.DropDown.Value
            updateimage(app, app.FileName, 0, 0, 0);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.jpeg;*.png;*.gif' ,'All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               app.FileName = [p f];
               [temp, coord] = imcrop(imread(app.FileName));
               textLabel = sprintf("%f",coord);
               imwrite(temp,"temp.png");
               app.FileName = "temp.png";
               app.CoordenadasEditField.Value = textLabel;
               drawnow
               updateimage(app, app.FileName, 0, 0, 0);
            end
        end

        % Value changed function: PixelSlider
        function PixelSliderValueChanged(app, event)
            app.pixelValue = app.PixelSlider.Value;
            updateimage(app, app.FileName, 1, app.PixelSlider.Value, app.SensibilidadeSlider.Value);
        end

        % Value changed function: SensibilidadeSlider
        function SensibilidadeSliderValueChanged(app, event)
            app.SensiValue = app.SensibilidadeSlider.Value;
            updateimage(app, app.FileName, 1, app.PixelSlider.Value, app.SensibilidadeSlider.Value);
        end

        % Value changed function: MaxSlider
        function MaxSliderValueChanged(app, event)
            app.MaxValue = app.MaxSlider.Value;
            app.MaxValue = ceil(app.MaxValue);
            updateimage(app, app.FileName, 1, app.PixelSlider.Value, app.SensibilidadeSlider.Value);
        end

        % Value changed function: MinSlider
        function MinSliderValueChanged(app, event)
            app.MinValue = app.MinSlider.Value;
            app.MinValue = ceil(app.MinValue);
            updateimage(app, app.FileName, 1, app.PixelSlider.Value, app.SensibilidadeSlider.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.BlueAxes = uiaxes(app.UIFigure);
            title(app.BlueAxes, 'Blue')
            xlabel(app.BlueAxes, 'Intensity')
            ylabel(app.BlueAxes, 'Pixels')
            app.BlueAxes.XLim = [0 255];
            app.BlueAxes.XTick = [0 128 255];
            app.GreenAxes = uiaxes(app.UIFigure);
            title(app.GreenAxes, 'Green')
            xlabel(app.GreenAxes, 'Intensity')
            ylabel(app.GreenAxes, 'Pixels')
            app.GreenAxes.XLim = [0 255];
            app.GreenAxes.XTick = [0 128 255];
            app.RedAxes = uiaxes(app.UIFigure);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Intensity')
            ylabel(app.RedAxes, 'Pixels')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Imagem 1', 'Imagem 2'};
            app.DropDown.ItemsData = {'template1.jpg', 'template2.jpg'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'template1.jpg';
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = 'Predefined Images';
            app.SensibilidadeSliderLabel = uilabel(app.UIFigure);
            app.SensibilidadeSliderLabel.Text = 'Sensibilidade';
            app.SensibilidadeSlider = uislider(app.UIFigure);
            app.SensibilidadeSlider.Limits = [0 1];
            app.SensibilidadeSlider.ValueChangedFcn = createCallbackFcn(app, @SensibilidadeSliderValueChanged, true);
            app.PixelSliderLabel = uilabel(app.UIFigure);
            app.PixelSliderLabel.Text = 'Pixel';
            app.PixelSlider = uislider(app.UIFigure);
            app.PixelSlider.Limits = [0 1000];
            app.PixelSlider.ValueChangedFcn = createCallbackFcn(app, @PixelSliderValueChanged, true);
            app.CoordenadasEditFieldLabel = uilabel(app.UIFigure);
            app.CoordenadasEditFieldLabel.Text = 'Coordenadas';
            app.CoordenadasEditField = uieditfield(app.UIFigure, 'text');
            app.CoordenadasEditField.Editable = 'off';
            app.MinSliderLabel = uilabel(app.UIFigure);
            app.MinSliderLabel.Enable = 'off';
            app.MinSliderLabel.Visible = 'off';
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.UIFigure);
            app.MinSlider.Limits = [1 5000];
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @MinSliderValueChanged, true);
            app.MinSlider.Enable = 'off';
            app.MinSlider.Visible = 'off';
            app.MinSlider.Value = 1;
            app.MaxSliderLabel = uilabel(app.UIFigure);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.UIFigure);
            app.MaxSlider.Limits = [1 10000];
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MaxSliderValueChanged, true);
            app.MaxSlider.Visible = 'off';
            app.MaxSlider.Value = 200;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Seletor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end